classdef app_halley_method < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                        matlab.ui.Figure
        WybierzwykadniktolerancjidlametodyHalleya210wartoSlider  matlab.ui.control.Slider
        WybierzwykadniktolerancjidlametodyHalleya210wartoSliderLabel  matlab.ui.control.Label
        Label_2                         matlab.ui.control.Label
        Zmieniajwykadniktolerancjibdu210wartoSlider  matlab.ui.control.Slider
        Zmieniajwykadniktolerancjibdu210wartoSliderLabel  matlab.ui.control.Label
        UITable                         matlab.ui.control.Table
        WybierzwielomianwDropDown       matlab.ui.control.DropDown
        WybierzwielomianwDropDownLabel  matlab.ui.control.Label
        Label                           matlab.ui.control.Label
        UIAxes2                         matlab.ui.control.UIAxes
        UIAxes                          matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        sliderEps (1,1) double = -16 % Wartość współczynnika tolerancji błędu dla metody Halley'a
        aPolynomial % Współczynniki wybranego wielomianu
        alfaRoots % Miejsca zerowe wybranego wielomianu
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Value changed function: WybierzwielomianwDropDown
        function WybierzwielomianwDropDownValueChanged(app, event)
            value = str2double(app.WybierzwielomianwDropDown.Value);
            switch value
                case 1
                    a = [8 0 -12 0]; 
                    alfa = [sqrt(3/2), -sqrt(3/2), 0]';
                case 2
                    a =[1 0 -30 0 273 0 -820 0 576];
                    alfa = [1 2 3 4 -1 -2 -3 -4]';
                case 3
                    a = [1 0 -2 2];
                    real_alfa = -(nthroot(9 - sqrt(57), 3)/3^(2/3)) - 2/nthroot(3*(9-sqrt(57)), 3);
                    alfa = real_alfa';
                case 4
                    %x^3 - 3.003 x^2 + 3.006 x - 1.003
                    a = [1 -3.003 3.006 -1.003];
                    alfa = [1, 1.001, 1.002]';
                case 5
                    a = [1 2 -11 -12 36];
                    alfa = [-3, 2]';
                case 6
                    a = [1 -6 11 -6];
                    alfa = [1 2 3]';
            end
            app.aPolynomial = a;
            app.alfaRoots = alfa;
        end

        % Value changed function: 
        % Zmieniajwykadniktolerancjibdu210wartoSlider
        function Zmieniajwykadniktolerancjibdu210wartoSliderValueChanged(app, event)
            
        end

        % Value changing function: 
        % Zmieniajwykadniktolerancjibdu210wartoSlider
        function Zmieniajwykadniktolerancjibdu210wartoSliderValueChanging(app, event)
            changingValue = event.Value;
            a = app.aPolynomial;
            alfa = app.alfaRoots;

            %przed pierwszą zmianą dropdown wartości nie są
            %zainicjalizowane więc ustawiamy na pierwsze z dropdowan
            if length(a) < 1
                 a = [8 0 -12 0]; 
                 alfa = [sqrt(3/2), -sqrt(3/2), 0]';
            end
           
            eps = 10^(changingValue);
            epsH = 10^(app.sliderEps);
            [x1, y1] = convergace_fun(alfa, a, -10, 10, 0.1, 100, eps, epsH);
            [x2, y2] = convergace_fun(alfa, conv(a, a), -10, 10, 0.1, 100, eps, epsH);
            M = max([y1, y2]);
            app.UIAxes.YLim = [-2, M+1];
            plot(app.UIAxes, x1, y1, 'b-o');
            hold(app.UIAxes, "on");
            yline(app.UIAxes, 0, 'r--');
            subtitle(app.UIAxes, strcat("Tolerancja różnicy pomiędzy dokładnym, a wyznaczonym pierwiastkiem: ", num2str(eps)));
            grid(app.UIAxes,'on');
            xline(app.UIAxes, alfa, 'm--');
            hold(app.UIAxes, "off");

            plot(app.UIAxes2, x2, y2, 'b-o');
            app.UIAxes2.YLim = [-2, M+1];
            hold(app.UIAxes2, "on");
            yline(app.UIAxes2, 0, 'r--');
            subtitle(app.UIAxes2, strcat("Tolerancja różnicy pomiędzy dokładnym, a wyznaczonym pierwiastkiem: ", num2str(eps)));
            grid(app.UIAxes2,'on');
            xline(app.UIAxes2, alfa, 'm--');
            hold(app.UIAxes2, "off");

            app.UITable.Data = alfa;
        end

        % Drop down opening function: WybierzwielomianwDropDown
        function WybierzwielomianwDropDownOpening(app, event)
            a =[1 0 -30 0 273 0 -820 0 576];
            alfa = [1 2 3 4 -1 -2 -3 -4]';
            app.aPolynomial = a;
            app.alfaRoots = alfa;
        end

        % Value changed function: 
        % WybierzwykadniktolerancjidlametodyHalleya210wartoSlider
        function WybierzwykadniktolerancjidlametodyHalleya210wartoSliderValueChanged(app, event)
            value = app.WybierzwykadniktolerancjidlametodyHalleya210wartoSlider.Value;
            app.sliderEps = value;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Ilość iteracji dla wielomianu w')
            xlabel(app.UIAxes, 'Przybliżenie początkowe x0')
            ylabel(app.UIAxes, 'Liczbna iteracji')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Ilość iteracji dla wielomianu w^2')
            xlabel(app.UIAxes2, 'Przybliżenie początkowe x0')
            ylabel(app.UIAxes2, 'Liczba iteracji')
            zlabel(app.UIAxes2, 'Z')
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = 'Porównanie liczby iteracji potrzebnych do osiągnięcia miejsca zerowego wielomianu';
            app.WybierzwielomianwDropDownLabel = uilabel(app.UIFigure);
            app.WybierzwielomianwDropDownLabel.Text = 'Wybierz wielomian w';
            app.WybierzwielomianwDropDown = uidropdown(app.UIFigure);
            app.WybierzwielomianwDropDown.Items = {'8x^3-12x', 'x^8-30x^6+273x^4-820x^2+576', 'x^3-2x+2', 'x^3 - 3.003 x^2 + 3.006 x - 1.003', 'x^4+2x^3-11x^2-12x+36', '𝑥^3−6𝑥^2+11𝑥−6'};
            app.WybierzwielomianwDropDown.ItemsData = {'1', '2', '3', '4', '5', '6'};
            app.WybierzwielomianwDropDown.DropDownOpeningFcn = createCallbackFcn(app, @WybierzwielomianwDropDownOpening, true);
            app.WybierzwielomianwDropDown.ValueChangedFcn = createCallbackFcn(app, @WybierzwielomianwDropDownValueChanged, true);
            app.WybierzwielomianwDropDown.Value = '1';
            app.UITable = uitable(app.UIFigure);
            app.UITable.ColumnName = {'Pierwiastki'};
            app.UITable.RowName = {};
            app.Zmieniajwykadniktolerancjibdu210wartoSliderLabel = uilabel(app.UIFigure);
            app.Zmieniajwykadniktolerancjibdu210wartoSliderLabel.Text = 'Zmieniaj wykładnik tolerancji błędu (2*10^wartość)';
            app.Zmieniajwykadniktolerancjibdu210wartoSlider = uislider(app.UIFigure);
            app.Zmieniajwykadniktolerancjibdu210wartoSlider.Limits = [-16 0];
            app.Zmieniajwykadniktolerancjibdu210wartoSlider.ValueChangedFcn = createCallbackFcn(app, @Zmieniajwykadniktolerancjibdu210wartoSliderValueChanged, true);
            app.Zmieniajwykadniktolerancjibdu210wartoSlider.ValueChangingFcn = createCallbackFcn(app, @Zmieniajwykadniktolerancjibdu210wartoSliderValueChanging, true);
            app.Zmieniajwykadniktolerancjibdu210wartoSlider.Value = -16;
            app.Label_2 = uilabel(app.UIFigure);
            app.Label_2.Text = 'W przypadku, gdy ilość iteracji = -1 nie można osiągnąć zbieżności do pierwiastka z danym przybliżeniem początkowym oraz daną tolerancją błędu';
            app.WybierzwykadniktolerancjidlametodyHalleya210wartoSliderLabel = uilabel(app.UIFigure);
            app.WybierzwykadniktolerancjidlametodyHalleya210wartoSliderLabel.Text = 'Wybierz wykładnik tolerancji dla metody Halley''a (2*10^wartość)';
            app.WybierzwykadniktolerancjidlametodyHalleya210wartoSlider = uislider(app.UIFigure);
            app.WybierzwykadniktolerancjidlametodyHalleya210wartoSlider.Limits = [-16 0];
            app.WybierzwykadniktolerancjidlametodyHalleya210wartoSlider.ValueChangedFcn = createCallbackFcn(app, @WybierzwykadniktolerancjidlametodyHalleya210wartoSliderValueChanged, true);
            app.WybierzwykadniktolerancjidlametodyHalleya210wartoSlider.Value = -16;
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app_halley_method

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end